classdef only_prediction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        predictionButton    matlab.ui.control.Button
        PleaseclickthebuttontodotestingLabel  matlab.ui.control.Label
        realdatafileButton  matlab.ui.control.Button
        newtworkfileButton  matlab.ui.control.Button
        PleaseclickthebuttontobrowsetherealdatafileLabel  matlab.ui.control.Label
        PleaseclickthebuttontobrowsethetrainednetworkfileLabel  matlab.ui.control.Label
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingapp

        frequency
        time  
        
        network  
        output2% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, start)
            app.callingapp = start;
        end

        % Button pushed function: newtworkfileButton
        function newtworkfileButtonPushed(app, event)
            [name,path]=uigetfile(('*.mat'), 'Search file to be loaded');
            net_file=[path name];
            app.network = load (net_file, 'net');

        end

        % Button pushed function: realdatafileButton
        function realdatafileButtonPushed(app, event)
            [name,path]=uigetfile(('*.xlsx'), 'Search file to be loaded');
            fullname=[path name];
            data=xlsread(fullname);
            app.frequency=data;
            app.time =data;
        end

        % Button pushed function: predictionButton
        function predictionButtonPushed(app, event)
            data = app.frequency;
            %data = raw;
            %data = rmmissing(data);
                               
            %%% partition dataset into training and testing set -- 90%:10% of the data
            numTimeStepsTrain = floor(0.9*size(data));
            dataTrain = data(1:numTimeStepsTrain);
            dataTest = data(numTimeStepsTrain+1:end);
            
            %%% mean and std of training dataset
            muX = mean(dataTrain(1:end-1));
            sigX = std(dataTrain(1:end-1));
            muY = mean(dataTrain(2:end));
            sigY = std(dataTrain(2:end));
            
            %%% normalization of testing set
            XTest = (dataTest(1:end-1)-muX)./sigX;
            TTest = (dataTest(2:end)-muY)./sigY;
            
            %%%predict using predict function
            network1 = app.network;
            net = network1.net ;
            YTest = predict(net, XTest','SequencePaddingDirection','left');
            
            %%%plotting of un-normalized data
            ax = app.UIAxes;
            %y = 50*dataTest/dataTest;
            hold (ax, 'on');
            plot(ax, (sigY)*YTest+muY, 'r');
            plot(ax, dataTest, 'b');
            %plot(ax, y, 'k');
            legend(ax, 'Predicted','Actual');
            xlabel(ax, "Time (min)");
            ylabel(ax, "Frequency (Hz)");
            title(ax, 'Prediction of frequency');
            hold (ax, 'off');
                        
            %%% plotting of errors
            ax1 = app.UIAxes2;
            rmse=sqrt(mean((YTest-TTest).^2));
            mnrmse=mean(rmse);
            stem(ax1, rmse)
            xlabel(ax1, "Time (min)");
            ylabel(ax1, "RMSE");
            title(ax1, "RMSE in Frequency with mean rmse="+mnrmse);

            mnrmse = double(mnrmse);
            app.output2 = output2(app, mnrmse,YTest,dataTest,muY,sigY);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.PleaseclickthebuttontobrowsethetrainednetworkfileLabel = uilabel(app.UIFigure);
            app.PleaseclickthebuttontobrowsethetrainednetworkfileLabel.Text = 'Please click the button to browse the trained network file :';
            app.PleaseclickthebuttontobrowsetherealdatafileLabel = uilabel(app.UIFigure);
            app.PleaseclickthebuttontobrowsetherealdatafileLabel.Text = 'Please click the button to browse the real data file :';
            app.newtworkfileButton = uibutton(app.UIFigure, 'push');
            app.newtworkfileButton.ButtonPushedFcn = createCallbackFcn(app, @newtworkfileButtonPushed, true);
            app.newtworkfileButton.Text = 'newtwork file';
            app.realdatafileButton = uibutton(app.UIFigure, 'push');
            app.realdatafileButton.ButtonPushedFcn = createCallbackFcn(app, @realdatafileButtonPushed, true);
            app.realdatafileButton.Text = 'real data file';
            app.PleaseclickthebuttontodotestingLabel = uilabel(app.UIFigure);
            app.PleaseclickthebuttontodotestingLabel.Text = 'Please click the button to do testing :';
            app.predictionButton = uibutton(app.UIFigure, 'push');
            app.predictionButton.ButtonPushedFcn = createCallbackFcn(app, @predictionButtonPushed, true);
            app.predictionButton.Text = 'prediction';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = only_prediction(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end