classdef output2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RMSEEditField                 matlab.ui.control.NumericEditField
        RMSEEditFieldLabel            matlab.ui.control.Label
        AnimatedpredictionplotButton  matlab.ui.control.Button
        pleaseclickthebelowbuttontoplottheAnimatedplotLabel  matlab.ui.control.Label
        RMSEwegetafterthepredictionLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingapp 
        dataTest
        YTest
        muY 
        sigY % Description % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, only_prediction, mnrmse, YTest, dataTest, muY, sigY)
            app.callingapp = only_prediction;
            app.dataTest = dataTest;
            app.YTest = YTest;
            app.muY = muY;
            app.sigY = sigY;
            app.RMSEEditField.Value = mnrmse;
        end

        % Value changed function: RMSEEditField
        function RMSEEditFieldValueChanged(app, event)
            value = app.RMSEEditField.Value;
            
        end

        % Button pushed function: AnimatedpredictionplotButton
        function AnimatedpredictionplotButtonPushed(app, event)
            %%%animated 2-D plot
            ax = app.UIAxes;
            app.UIFigure;
            x=0:1:length(app.dataTest);
            y=(app.sigY)*(app.YTest)+app.muY;
            z=app.dataTest;
            for i= 1:length(x)
                plot(ax, x(i), y(i), 'r', 'Linewidth', 2,'LineStyle','-.');
                plot(ax, x(i), z(i), 'b', 'Linewidth', 1);
                hold (ax, 'on');
                plot(ax, x(1:i), y(1:i),'r', 'Linewidth', 2,'LineStyle','-.');
                plot(ax, x(1:i), z(1:i),'b', 'Linewidth', 1);
                legend(ax, 'Actual','Predicted');
                xlabel(ax, "Time (min)");
                ylabel(ax, "Frequency (Hz)");
                title(ax, 'Prediction of frequency');
                %axis([0 x 49.7 50.3]);
                pause(0.01);
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RMSEwegetafterthepredictionLabel = uilabel(app.UIFigure);
            app.RMSEwegetafterthepredictionLabel.Text = 'RMSE we get after the prediction : ';
            app.pleaseclickthebelowbuttontoplottheAnimatedplotLabel = uilabel(app.UIFigure);
            app.pleaseclickthebelowbuttontoplottheAnimatedplotLabel.Text = 'please click the below button to plot the Animated plot';
            app.AnimatedpredictionplotButton = uibutton(app.UIFigure, 'push');
            app.AnimatedpredictionplotButton.ButtonPushedFcn = createCallbackFcn(app, @AnimatedpredictionplotButtonPushed, true);
            app.AnimatedpredictionplotButton.Text = 'Animated prediction plot';
            app.RMSEEditFieldLabel = uilabel(app.UIFigure);
            app.RMSEEditFieldLabel.Text = 'RMSE';
            app.RMSEEditField = uieditfield(app.UIFigure, 'numeric');
            app.RMSEEditField.ValueChangedFcn = createCallbackFcn(app, @RMSEEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = output2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end