classdef prediction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        CheckValidationButton  matlab.ui.control.Button
        Label                  matlab.ui.control.Label
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingapp 
        output1 % Description
        
        net
        dataTest
        muX
        sigX
        muY
        sigY % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, training, dataTest, muX, muY, sigX, sigY, trained_network)
            app.callingapp = training;

            app.dataTest = dataTest;
            app.muX = muX;
            app.sigX = sigX;
            app.muY = muY;
            app.sigY = sigY;
                      
            app.net = trained_network;
        end

        % Button pushed function: CheckValidationButton
        function CheckValidationButtonPushed(app, event)
            %%% normalization of testing set
            XTest = (app.dataTest(1:end-1)-app.muX)./app.sigX;
            TTest = (app.dataTest(2:end)-app.muY)./app.sigY;
            
            %%%predict using predict function
            YTest = predict(app.net,XTest','SequencePaddingDirection','left');
              
            %%%plotting of un-normalized data
            ax = app.UIAxes;
            %y = 50*app.dataTest/app.dataTest;
            hold (ax, 'on');
            plot(ax, (app.sigY)*YTest+app.muY, 'r');
            %plot(ax, app.dataTest, 'b');
            plot(ax, y, 'k');
            legend(ax, 'Predicted','Actual');
            xlabel(ax, "Time (min)");
            ylabel(ax, "Frequency (Hz)");
            title(ax, 'Prediction of frequency');
            hold (ax, 'off');
                        
            %%% plotting of errors
            ax1 = app.UIAxes2;
            rmse=sqrt(mean((YTest-TTest).^2));
            mnrmse=mean(rmse);
            stem(ax1, rmse)
            xlabel(ax1, "Time (min)");
            ylabel(ax1, "RMSE");
            title(ax1, "RMSE in Frequency with mean rmse="+mnrmse);

            mnrmse = double(mnrmse);
            
            dataTest1 = app.dataTest;
            muY1 = app.muY;
            sigY1 = app.sigY;
            app.output1 = output1(app, mnrmse,YTest,dataTest1,muY1,sigY1);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Please click the button to predict the 10% remaining data '; 'and compare with that 10% input data'};
            app.CheckValidationButton = uibutton(app.UIFigure, 'push');
            app.CheckValidationButton.ButtonPushedFcn = createCallbackFcn(app, @CheckValidationButtonPushed, true);
            app.CheckValidationButton.Text = 'Check Validation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prediction(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end