classdef training < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Label                         matlab.ui.control.Label
        MaximumiterationsEditField    matlab.ui.control.NumericEditField
        MaximumiterationsEditFieldLabel  matlab.ui.control.Label
        InitiallearningrateEditField  matlab.ui.control.NumericEditField
        InitiallearningrateEditFieldLabel  matlab.ui.control.Label
        LSTMlayersEditField           matlab.ui.control.NumericEditField
        LSTMlayersEditFieldLabel      matlab.ui.control.Label
        Pleaseclickthebuttontotrainthe90inputdataLabel  matlab.ui.control.Label
        Giveavaluegreaterthan0Herewehavegivendefaultvalueas10Label  matlab.ui.control.Label
        Enteroneofthegvenlearningratesvalues00501015025Label  matlab.ui.control.Label
        EnteroneofthevaluesofLSTMlayer8163264128Label  matlab.ui.control.Label
        PleasebrowseandinputyourrealdatafileLabel  matlab.ui.control.Label
        TrainNetworkButton            matlab.ui.control.Button
        BrowsefileButton              matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingapp
        frequency
        time

        prediction % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, start)
            app.callingapp = start;
        end

        % Button pushed function: BrowsefileButton
        function BrowsefileButtonPushed(app, event)
            [name,path]=uigetfile(('*.xlsx'), 'Search file to be loaded');
            fullname=[path name];
            data=xlsread(fullname);
            app.frequency=data;
            app.time =data;
            plot(app.UIAxes,data)
        end

        % Value changed function: LSTMlayersEditField
        function LSTMlayersEditFieldValueChanged(app, event)
            value = app.LSTMlayersEditField.Value;
            
        end

        % Value changed function: InitiallearningrateEditField
        function InitiallearningrateEditFieldValueChanged(app, event)
            value = app.InitiallearningrateEditField.Value;
            
        end

        % Value changed function: MaximumiterationsEditField
        function MaximumiterationsEditFieldValueChanged(app, event)
            value = app.MaximumiterationsEditField.Value;
            
        end

        % Button pushed function: TrainNetworkButton
        function TrainNetworkButtonPushed(app, event)
            raw = app.frequency;
            data = raw;
            data = rmmissing(data);
                               
            %%% partition dataset into training and testing set -- 90%:10% of the data
            numTimeStepsTrain = floor(0.9*size(data));
            dataTrain = data(1:numTimeStepsTrain);
            dataTest = data(numTimeStepsTrain+1:end);
            
            %%% mean and std of training dataset
            muX = mean(dataTrain(1:end-1));
            sigX = std(dataTrain(1:end-1));
            muY = mean(dataTrain(2:end));
            sigY = std(dataTrain(2:end));
            
            %%% normalization of training set
            
            XTrain = (dataTrain(1:end-1)-muX)./sigX;
            YTrain = (dataTrain(2:end)-muY)./sigY;
            
            %%% configuring the NN
            layers = [
                sequenceInputLayer(1)
                lstmLayer(app.LSTMlayersEditField.Value)
                fullyConnectedLayer(1)
                regressionLayer];
            
            options = trainingOptions("adam",...      
                MaxEpochs=app.MaximumiterationsEditField.Value,...
                InitialLearnRate=app.InitiallearningrateEditField.Value,...
                ExecutionEnvironment="auto",...
                SequencePaddingDirection="left", ...
                Shuffle="every-epoch", ...
                Plots="training-progress", ...
                Verbose=0);
        
            %%% training
            net = trainNetwork(XTrain',YTrain',layers,options);

            trained_network = net;
            filename = ['network', datestr(now,'dd-mmm-yyyy_HH-MM-SS')];
            save (filename,'net');
            load (filename);

            app.prediction = prediction(app, dataTest, muX, muY, sigX, sigY, trained_network);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time series Data vs Time')
            xlabel(app.UIAxes, 'Time(min)')
            ylabel(app.UIAxes, 'Frequency (Hz)')
            zlabel(app.UIAxes, 'Z')
            app.BrowsefileButton = uibutton(app.UIFigure, 'push');
            app.BrowsefileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsefileButtonPushed, true);
            app.BrowsefileButton.Text = 'Browse file';
            app.TrainNetworkButton = uibutton(app.UIFigure, 'push');
            app.TrainNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @TrainNetworkButtonPushed, true);
            app.TrainNetworkButton.Text = 'Train Network';
            app.PleasebrowseandinputyourrealdatafileLabel = uilabel(app.UIFigure);
            app.PleasebrowseandinputyourrealdatafileLabel.Text = 'Please browse and input your real data file :';
            app.EnteroneofthevaluesofLSTMlayer8163264128Label = uilabel(app.UIFigure);
            app.EnteroneofthevaluesofLSTMlayer8163264128Label.Text = 'Enter one of the values of LSTM layer (8, 16, 32, 64, 128) :';
            app.Enteroneofthegvenlearningratesvalues00501015025Label = uilabel(app.UIFigure);
            app.Enteroneofthegvenlearningratesvalues00501015025Label.Text = 'Enter one of the gven learning rates values (0.05, 0.1, 0.15, 0.25) :';
            app.Giveavaluegreaterthan0Herewehavegivendefaultvalueas10Label = uilabel(app.UIFigure);
            app.Giveavaluegreaterthan0Herewehavegivendefaultvalueas10Label.Text = 'Give a value greater than 0. Here we have given default value as 10 :';
            app.Pleaseclickthebuttontotrainthe90inputdataLabel = uilabel(app.UIFigure);
            app.Pleaseclickthebuttontotrainthe90inputdataLabel.Text = 'Please click the button to train the 90% input data :';
            app.LSTMlayersEditFieldLabel = uilabel(app.UIFigure);
            app.LSTMlayersEditFieldLabel.Text = 'LSTM layers';
            app.LSTMlayersEditField = uieditfield(app.UIFigure, 'numeric');
            app.LSTMlayersEditField.ValueChangedFcn = createCallbackFcn(app, @LSTMlayersEditFieldValueChanged, true);
            app.LSTMlayersEditField.Value = 8;
            app.InitiallearningrateEditFieldLabel = uilabel(app.UIFigure);
            app.InitiallearningrateEditFieldLabel.Text = 'Initial learning rate';
            app.InitiallearningrateEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitiallearningrateEditField.ValueChangedFcn = createCallbackFcn(app, @InitiallearningrateEditFieldValueChanged, true);
            app.InitiallearningrateEditField.Value = 0.05;
            app.MaximumiterationsEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumiterationsEditFieldLabel.Text = 'Maximum iterations ';
            app.MaximumiterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumiterationsEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumiterationsEditFieldValueChanged, true);
            app.MaximumiterationsEditField.Value = 10;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Welcome to the time series data prediction app!'; 'This app uses LSTM to predict the behavior of a given time series dataset.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = training(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end